classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main              matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        ChuKySoTab        matlab.ui.container.Tab
        btnMoFileCK       matlab.ui.control.Button
        entryFileCK       matlab.ui.control.EditField
        textBoxND2        matlab.ui.control.TextArea
        lblTextBox3       matlab.ui.control.Label
        lblND2            matlab.ui.control.Label
        textBoxCK2        matlab.ui.control.TextArea
        lblChia2          matlab.ui.control.Label
        btnLuuFileCK      matlab.ui.control.Button
        btnChuyenTiep     matlab.ui.control.Button
        textBoxND1        matlab.ui.control.TextArea
        lblND1            matlab.ui.control.Label
        lblKiemTraCK      matlab.ui.control.Label
        lblChia1          matlab.ui.control.Label
        btnKTChuKy        matlab.ui.control.Button
        btnMoFileKT       matlab.ui.control.Button
        entryFileKT       matlab.ui.control.EditField
        lblChonFileKT     matlab.ui.control.Label
        textBoxCK1        matlab.ui.control.TextArea
        lblTextBox        matlab.ui.control.Label
        btnKyVanBan       matlab.ui.control.Button
        btnMoFileKy       matlab.ui.control.Button
        entryFileKy       matlab.ui.control.EditField
        lblChonFileKy     matlab.ui.control.Label
        entryGamal        matlab.ui.control.EditField
        lblSoGamal        matlab.ui.control.Label
        entryK            matlab.ui.control.EditField
        lblSoK            matlab.ui.control.Label
        lblPhatSinhCK     matlab.ui.control.Label
        btnThoat          matlab.ui.control.Button
        btnXoaDuLieu      matlab.ui.control.Button
        btnTaoKhoa        matlab.ui.control.Button
        entryA            matlab.ui.control.EditField
        lblSoA            matlab.ui.control.Label
        entryBeta         matlab.ui.control.EditField
        entryAlpha        matlab.ui.control.EditField
        entryP            matlab.ui.control.EditField
        lblSoBeta         matlab.ui.control.Label
        lblSoAlpha        matlab.ui.control.Label
        lblSoP            matlab.ui.control.Label
        lblKhoaBiMat      matlab.ui.control.Label
        lblKhoaCongKhai   matlab.ui.control.Label
        lblTaoKhoa        matlab.ui.control.Label
        TacGiaTab         matlab.ui.container.Tab
        Image2            matlab.ui.control.Image
        THNGTINTCGILabel  matlab.ui.control.Label
        HuongDanTab       matlab.ui.container.Tab
        Image             matlab.ui.control.Image
        Hyperlink         matlab.ui.control.Hyperlink
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnTaoKhoa
        function taoKhoa(app, event)
            keys = cre_key();
            app.entryP.Value = num2str(keys{1});
            app.entryAlpha.Value = num2str(keys{2});
            app.entryBeta.Value = num2str(keys{3});
            app.entryA.Value = num2str(keys{4});
            app.entryK.Value = num2str(keys{5});
            app.entryGamal.Value = num2str(keys{6});
        end

        % Button pushed function: btnThoat
        function thoat(app, event)
            delete(app.Main)
        end

        % Button pushed function: btnXoaDuLieu
        function xoaDuLieu(app, event)
            app.entryP.Value = '';
            app.entryAlpha.Value = '';
            app.entryBeta.Value = '';
            app.entryA.Value = '';
            app.entryK.Value = '';
            app.entryGamal.Value = '';
            app.entryFileKy.Value = '';
            app.entryFileKT.Value = '';
            app.entryFileCK.Value = '';
            app.textBoxCK1.Value = '';
            app.textBoxCK2.Value = '';
            app.textBoxND1.Value = '';
            app.textBoxND2.Value = '';
        end

        % Button pushed function: btnMoFileKy
        function moFileKy(app, event)
            [filename, pathname] = uigetfile("*.*", "Chọn file ký");
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                pathfile = fullfile(pathname, filename);
                app.entryFileKy.Value = pathfile;
                fileid = fopen(app.entryFileKy.Value);
                format = "%c";
                data = fscanf(fileid, format);
                fclose(fileid);
                app.textBoxND1.Value = data;
            end
        end

        % Button pushed function: btnMoFileKT
        function moFileKT(app, event)
            [filename, pathname] = uigetfile("*.*", "Chọn file kiểm tra");
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                pathfile = fullfile(pathname, filename);
                app.entryFileKT.Value = pathfile;
                fileid = fopen(app.entryFileKT.Value);
                format = "%c";
                data = fscanf(fileid, format);
                fclose(fileid);
                app.textBoxND2.Value = data;
            end
        end

        % Button pushed function: btnKyVanBan
        function kyVanBan(app, event)
            data = app.textBoxND1.Value;
            data = join(data, newline);
            if isempty(data)
                errordlg("Nội dung không được để trống!", "Lỗi");
                return;
            end
            try
                md = java.security.MessageDigest.getInstance('SHA-256');
                md.update(uint8(data{1})); % Chuyển đổi phần tử đầu tiên sang uint8
                for i = 2:numel(data)
                    md.update(newline); % Thêm ký tự xuống dòng
                    md.update(uint8(data{i})); % Chuyển đổi từng phần tử còn lại sang uint8
                end
                text_hash = reshape(dec2hex(typecast(md.digest(), 'uint8'))', 1, []);
                gamal = str2double(app.entryGamal.Value);
                a = str2double(app.entryA.Value);
                k = str2double(app.entryK.Value);
                p = str2double(app.entryP.Value);
                app.textBoxCK1.Value = create_sign(text_hash, gamal, a, k, p);
            catch
                errordlg("Vui lòng kiểm tra lại các trường dữ liệu!", "Lỗi");
            end
        end

        % Button pushed function: btnMoFileCK
        function moFileCK(app, event)
            [filename, pathname] = uigetfile("*.*", "Chọn file kiểm tra");
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else
                pathfile = fullfile(pathname, filename);
                app.entryFileCK.Value = pathfile;
                fileid = fopen(app.entryFileCK.Value);
                format = "%c";
                data = fscanf(fileid, format);
                fclose(fileid);
                app.textBoxCK2.Value = data;
            end
        end

        % Button pushed function: btnLuuFileCK
        function luuCK(app, event)
            data = char(app.textBoxCK1.Value);
            if isempty(data)
                errordlg("Nội dung không được để trống!", "Lỗi");
                return;
            end
            [file, path] = uiputfile('*.txt', 'Chọn tệp để lưu');
            if isequal(file, 0) || isequal(path, 0)
                disp('Người dùng không chọn tệp');
            else
                full_path = fullfile(path, file);
                fid = fopen(full_path, 'w');
                if fid == -1
                    errordlg("Không thể tạo hoặc mở tệp để ghi!", "Lỗi");
                    return;
                end
                fprintf(fid, '%s', data);
                fclose(fid);
                msgbox("Lưu file chữ ký thành công!" + newline + full_path, "Thông báo");
            end
        end

        % Button pushed function: btnChuyenTiep
        function chuyentiep(app, event)
            app.textBoxND2.Value = app.textBoxND1.Value;
            app.textBoxCK2.Value = app.textBoxCK1.Value;
        end

        % Button pushed function: btnKTChuKy
        function ktChuKy(app, event)
            try
                beta = str2double(app.entryBeta.Value);
                gamal = str2double(app.entryGamal.Value);
                alpha = str2double(app.entryAlpha.Value);
                p = str2double(app.entryP.Value);
            catch
                errordlg("Vui lòng kiểm tra lại khóa!", "Thông báo");
                return;
            end
            data = app.textBoxND2.Value;
            data = join(data, newline);
            if isempty(data)
                errordlg("Nội dung không được để trống!", "Lỗi");
                return;
            end
            try
                md = java.security.MessageDigest.getInstance('SHA-256');
                md.update(uint8(data{1}));
                for i = 2:numel(data)
                    md.update(newline);
                    md.update(uint8(data{i}));
                end
                text_hash = reshape(dec2hex(typecast(md.digest(), 'uint8'))', 1, []);
                sign = char(get(app.textBoxCK2, 'Value'));
            catch
                errordlg("Vui lòng kiểm tra lại các trường dữ liệu!", "Lỗi");
            end
            try
                check = verify_sign(text_hash, sign, beta, gamal, alpha, p);
                if check
                    msgbox("Văn bản chưa bị thay đổi!", "Thông báo");
                else
                    msgbox("Văn bản đã bị thay đổi!", "Thông báo");
                end
            catch
                errordlg("Đã có lỗi xảy ra trong quá trình kiểm tra!", "Lỗi");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Main = uifigure('Visible', 'off');
            app.Main.Name = 'Chữ ký số Elgamal - Trần Thị Hằng';
            app.TabGroup = uitabgroup(app.Main);
            app.ChuKySoTab = uitab(app.TabGroup);
            app.ChuKySoTab.Title = 'Chữ ký số Elgamal';
            app.lblTaoKhoa = uilabel(app.ChuKySoTab);
            app.lblTaoKhoa.Text = 'TẠO KHÓA';
            app.lblKhoaCongKhai = uilabel(app.ChuKySoTab);
            app.lblKhoaCongKhai.Text = 'Khóa công khai (p, α, β):';
            app.lblKhoaBiMat = uilabel(app.ChuKySoTab);
            app.lblKhoaBiMat.Text = 'Khóa bí mật (a):';
            app.lblSoP = uilabel(app.ChuKySoTab);
            app.lblSoP.Text = 'Số nguyên tố p = ';
            app.lblSoAlpha = uilabel(app.ChuKySoTab);
            app.lblSoAlpha.Text = 'Số (alpha) α = ';
            app.lblSoBeta = uilabel(app.ChuKySoTab);
            app.lblSoBeta.Text = 'Số β = α^x mod p = ';
            app.entryP = uieditfield(app.ChuKySoTab, 'text');
            app.entryAlpha = uieditfield(app.ChuKySoTab, 'text');
            app.entryBeta = uieditfield(app.ChuKySoTab, 'text');
            app.lblSoA = uilabel(app.ChuKySoTab);
            app.lblSoA.Text = 'Số nguyên a = ';
            app.entryA = uieditfield(app.ChuKySoTab, 'text');
            app.btnTaoKhoa = uibutton(app.ChuKySoTab, 'push');
            app.btnTaoKhoa.ButtonPushedFcn = createCallbackFcn(app, @taoKhoa, true);
            app.btnTaoKhoa.Text = 'Tạo khóa ngẫu nhiên';
            app.btnXoaDuLieu = uibutton(app.ChuKySoTab, 'push');
            app.btnXoaDuLieu.ButtonPushedFcn = createCallbackFcn(app, @xoaDuLieu, true);
            app.btnXoaDuLieu.Text = 'Xóa dữ liệu';
            app.btnThoat = uibutton(app.ChuKySoTab, 'push');
            app.btnThoat.ButtonPushedFcn = createCallbackFcn(app, @thoat, true);
            app.btnThoat.Text = 'Thoát';
            app.lblPhatSinhCK = uilabel(app.ChuKySoTab);
            app.lblPhatSinhCK.Text = 'PHÁT SINH CHỮ KÝ';
            app.lblSoK = uilabel(app.ChuKySoTab);
            app.lblSoK.Text = 'Số ngẫu nhiên k = ';
            app.entryK = uieditfield(app.ChuKySoTab, 'text');
            app.lblSoGamal = uilabel(app.ChuKySoTab);
            app.lblSoGamal.Text = 'Số γ = α^k mod p = ';
            app.entryGamal = uieditfield(app.ChuKySoTab, 'text');
            app.lblChonFileKy = uilabel(app.ChuKySoTab);
            app.lblChonFileKy.Text = 'Chọn file thực hiện ký:';
            app.entryFileKy = uieditfield(app.ChuKySoTab, 'text');
            app.btnMoFileKy = uibutton(app.ChuKySoTab, 'push');
            app.btnMoFileKy.ButtonPushedFcn = createCallbackFcn(app, @moFileKy, true);
            app.btnMoFileKy.Text = 'Mở file';
            app.btnKyVanBan = uibutton(app.ChuKySoTab, 'push');
            app.btnKyVanBan.ButtonPushedFcn = createCallbackFcn(app, @kyVanBan, true);
            app.btnKyVanBan.Text = 'Ký lên văn bản';
            app.lblTextBox = uilabel(app.ChuKySoTab);
            app.lblTextBox.Text = 'Nội dung chữ ký:';
            app.textBoxCK1 = uitextarea(app.ChuKySoTab);
            app.lblChonFileKT = uilabel(app.ChuKySoTab);
            app.lblChonFileKT.Text = 'Chọn file thực hiện kiểm tra chữ ký số:';
            app.entryFileKT = uieditfield(app.ChuKySoTab, 'text');
            app.btnMoFileKT = uibutton(app.ChuKySoTab, 'push');
            app.btnMoFileKT.ButtonPushedFcn = createCallbackFcn(app, @moFileKT, true);
            app.btnMoFileKT.Text = 'Mở file';
            app.btnKTChuKy = uibutton(app.ChuKySoTab, 'push');
            app.btnKTChuKy.ButtonPushedFcn = createCallbackFcn(app, @ktChuKy, true);
            app.btnKTChuKy.Text = 'Kiểm tra chữ ký';
            app.lblChia1 = uilabel(app.ChuKySoTab);
            app.lblChia1.Text = '';
            app.lblKiemTraCK = uilabel(app.ChuKySoTab);
            app.lblKiemTraCK.Text = 'KIỂM TRA CHỮ KÝ';
            app.lblND1 = uilabel(app.ChuKySoTab);
            app.lblND1.Text = 'Nội dung văn bản:';
            app.textBoxND1 = uitextarea(app.ChuKySoTab);
            app.btnChuyenTiep = uibutton(app.ChuKySoTab, 'push');
            app.btnChuyenTiep.ButtonPushedFcn = createCallbackFcn(app, @chuyentiep, true);
            app.btnChuyenTiep.Text = 'Chuyển tiếp';
            app.btnLuuFileCK = uibutton(app.ChuKySoTab, 'push');
            app.btnLuuFileCK.ButtonPushedFcn = createCallbackFcn(app, @luuCK, true);
            app.btnLuuFileCK.Text = 'Lưu file';
            app.lblChia2 = uilabel(app.ChuKySoTab);
            app.lblChia2.Text = '';
            app.textBoxCK2 = uitextarea(app.ChuKySoTab);
            app.lblND2 = uilabel(app.ChuKySoTab);
            app.lblND2.Text = 'Nội dung văn bản:';
            app.lblTextBox3 = uilabel(app.ChuKySoTab);
            app.lblTextBox3.Text = 'Nội dung chữ ký:';
            app.textBoxND2 = uitextarea(app.ChuKySoTab);
            app.entryFileCK = uieditfield(app.ChuKySoTab, 'text');
            app.btnMoFileCK = uibutton(app.ChuKySoTab, 'push');
            app.btnMoFileCK.ButtonPushedFcn = createCallbackFcn(app, @moFileCK, true);
            app.btnMoFileCK.Text = 'Mở file';
            app.TacGiaTab = uitab(app.TabGroup);
            app.TacGiaTab.Title = 'Thông tin tác giả';
            app.THNGTINTCGILabel = uilabel(app.TacGiaTab);
            app.THNGTINTCGILabel.Text = 'THÔNG TIN TÁC GIẢ';
            app.Image2 = uiimage(app.TacGiaTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'data', 'meo2.png');
            app.HuongDanTab = uitab(app.TabGroup);
            app.HuongDanTab.Title = 'Hướng dẫn ';
            app.Hyperlink = uihyperlink(app.HuongDanTab);
            app.Hyperlink.URL = 'https://github.com/tracy2048';
            app.Hyperlink.Text = 'XEM HƯỚNG DẪN TẠI ĐÂY';
            app.Image = uiimage(app.HuongDanTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'data', 'meo2.png');
            % Show the figure after all components are created
            app.Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main)
        end
    end
end